classdef MetodosNumericos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MtododeRungeKuttaButton       matlab.ui.control.Button
        MtododeEulerButton            matlab.ui.control.Button
        InterpolacindeLagrangeButton  matlab.ui.control.Button
        InterpolacindeNewtonButton    matlab.ui.control.Button
        RegresinPolinomicaButton      matlab.ui.control.Button
        RegresinLinealButton          matlab.ui.control.Button
        MtodoNewtonRaphsonButton      matlab.ui.control.Button
        MtodoBiseccinButton           matlab.ui.control.Button
        TaylorLnButton                matlab.ui.control.Button
        MtodosNumericosLabel          matlab.ui.control.Label
        TaylorArctanButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TaylorArctanButton
        function TaylorArctanButtonPushed(app, event)
            scriptName = 'arctanTaylor.m';
            run(scriptName);
        end

        % Button pushed function: TaylorLnButton
        function TaylorLnButtonPushed(app, event)
            scriptName = 'TaylorLn.m';
            run(scriptName);
        end

        % Button pushed function: MtodoBiseccinButton
        function MtodoBiseccinButtonPushed(app, event)
            scriptName = 'Biseccion.m';
            run(scriptName);
        end

        % Button pushed function: MtodoNewtonRaphsonButton
        function MtodoNewtonRaphsonButtonPushed(app, event)
            scriptName = 'funcionNewtonRaphson.m';
            run(scriptName);
        end

        % Button pushed function: RegresinLinealButton
        function RegresinLinealButtonPushed(app, event)
            scriptName = 'funcionRegresionLineal.m';
            run(scriptName);
        end

        % Button pushed function: RegresinPolinomicaButton
        function RegresinPolinomicaButtonPushed(app, event)
            scriptName = 'RegresionPolinomica.m';
            run(scriptName);
        end

        % Button pushed function: InterpolacindeNewtonButton
        function InterpolacindeNewtonButtonPushed(app, event)
            scriptName = 'funcionInterpolacionNewton.m';
            run(scriptName);
        end

        % Button pushed function: InterpolacindeLagrangeButton
        function InterpolacindeLagrangeButtonPushed(app, event)
            scriptName = 'InterpolacionLagrange.m';
            run(scriptName);
        end

        % Button pushed function: MtododeEulerButton
        function MtododeEulerButtonPushed(app, event)
            scriptName = 'metodoEuler.m';
            run(scriptName);
        end

        % Button pushed function: MtododeRungeKuttaButton
        function MtododeRungeKuttaButtonPushed(app, event)
            scriptName = 'funcionRungeKutta.m';
            run(scriptName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TaylorArctanButton = uibutton(app.UIFigure, 'push');
            app.TaylorArctanButton.ButtonPushedFcn = createCallbackFcn(app, @TaylorArctanButtonPushed, true);
            app.TaylorArctanButton.Text = 'Taylor Arctan';
            app.MtodosNumericosLabel = uilabel(app.UIFigure);
            app.MtodosNumericosLabel.Text = 'Métodos Numericos';
            app.TaylorLnButton = uibutton(app.UIFigure, 'push');
            app.TaylorLnButton.ButtonPushedFcn = createCallbackFcn(app, @TaylorLnButtonPushed, true);
            app.TaylorLnButton.Text = 'Taylor Ln';
            app.MtodoBiseccinButton = uibutton(app.UIFigure, 'push');
            app.MtodoBiseccinButton.ButtonPushedFcn = createCallbackFcn(app, @MtodoBiseccinButtonPushed, true);
            app.MtodoBiseccinButton.Text = 'Método Bisección';
            app.MtodoNewtonRaphsonButton = uibutton(app.UIFigure, 'push');
            app.MtodoNewtonRaphsonButton.ButtonPushedFcn = createCallbackFcn(app, @MtodoNewtonRaphsonButtonPushed, true);
            app.MtodoNewtonRaphsonButton.Text = 'Método Newton - Raphson';
            app.RegresinLinealButton = uibutton(app.UIFigure, 'push');
            app.RegresinLinealButton.ButtonPushedFcn = createCallbackFcn(app, @RegresinLinealButtonPushed, true);
            app.RegresinLinealButton.Text = 'Regresión Lineal';
            app.RegresinPolinomicaButton = uibutton(app.UIFigure, 'push');
            app.RegresinPolinomicaButton.ButtonPushedFcn = createCallbackFcn(app, @RegresinPolinomicaButtonPushed, true);
            app.RegresinPolinomicaButton.Text = 'Regresión Polinomica';
            app.InterpolacindeNewtonButton = uibutton(app.UIFigure, 'push');
            app.InterpolacindeNewtonButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolacindeNewtonButtonPushed, true);
            app.InterpolacindeNewtonButton.Text = 'Interpolación de Newton';
            app.InterpolacindeLagrangeButton = uibutton(app.UIFigure, 'push');
            app.InterpolacindeLagrangeButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolacindeLagrangeButtonPushed, true);
            app.InterpolacindeLagrangeButton.Text = 'Interpolación de Lagrange';
            app.MtododeEulerButton = uibutton(app.UIFigure, 'push');
            app.MtododeEulerButton.ButtonPushedFcn = createCallbackFcn(app, @MtododeEulerButtonPushed, true);
            app.MtododeEulerButton.Text = 'Método de Euler';
            app.MtododeRungeKuttaButton = uibutton(app.UIFigure, 'push');
            app.MtododeRungeKuttaButton.ButtonPushedFcn = createCallbackFcn(app, @MtododeRungeKuttaButtonPushed, true);
            app.MtododeRungeKuttaButton.Text = 'Método de Runge - Kutta';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetodosNumericos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end